classdef Q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        initializeButton         matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ChooseAxisDropDown       matlab.ui.control.DropDown
        ChooseAxisDropDownLabel  matlab.ui.control.Label
        thetaSlider              matlab.ui.control.Slider
        thetaSliderLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        % points1=[0 0 1;0 5 1;3 5 1;3 0 1; ...
        %     0 0 -1;0 5 -1;3 5 -1;3 0 -1]'; % Description
        % f=[1 2 3 4;
        %    5 6 7 8;
        %    1 4 8 5;
        %    4 3 7 8;
        %    3 7 6 2;
        %    1 5 6 2];
        % points1=[0 0 0;4 0 0;4 1 0;1 1 0;1 6 0;-3 6 0;-3 5 0;0 5 0; ...
        %          0 0 3;4 0 3;4 1 3;1 1 3;1 6 3;-3 6 3;-3 5 3;0 5 3]';
        % f=[1 2 3 4 5 6 7 8;
        %    9 10 11 12 13 14 15 16;
        %    1 2 10 9 nan(1, 4);
        %    1 8 16 9 nan(1, 4);
        %    7 8 16 15 nan(1, 4);
        %    7 15 14 6 nan(1, 4);
        %    5 13 14 6 nan(1, 4);
        %    4 5 13 12 nan(1, 4);
        %    3 4 12 11 nan(1, 4);
        %    2 3 11 10 nan(1, 4);];

        points1=[0 0 0;4 0 0;4 1 0;1 1 0;1 6 0;-3 6 0;-3 5 0;0 5 0; 
                 0 0 3;4 0 3;4 1 3;1 1 3;1 6 3;-3 6 3;-3 5 3;0 5 3;
                 -1 5 1;-1 5 2;-2 5 2;-2 5 1;-1 6 1;-1 6 2;-2 6 2;-2 6 1;
                 -1 5 0;-2 5 3;-1 6 0;-2 6 3]';
        f=[1 2 3 4 5 6 7 8;
           9 10 11 12 13 14 15 16;
           1 2 10 9 nan(1, 4);
           1 8 16 9 nan(1, 4);
           7 15 14 6 nan(1, 4);
           4 5 13 12 nan(1, 4);
           3 4 12 11 nan(1, 4);
           2 3 11 10 nan(1, 4);
           17 18 22 21 nan(1, 4);
           18 19 23 22 nan(1, 4);
           19 20 24 23 nan(1, 4);
           17 21 24 20 nan(1, 4);
           25 8 16 26 19 18 17 nan;
           26 19 20 17 25 7 15 nan;
           6 27 21 24 23 28 14 nan;
           27 21 22 23 28 13 5 nan;

           ];
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            th = event.Value;
            R=rotx(th);
            switch(app.ChooseAxisDropDown.Value)
                case'X'
                    R=rotx(th);
                case'Y'
                    R=roty(th);
                case'Z'
                    R=rotz(th);
            end
            app.UITable.Data = R;

            points0=R*app.points1;
            cla;

            %fill(points0(1,:),points0(2,:),points0(3,:),'g')
            numFaces = size(app.f, 1);
            colorMap = hsv(numFaces);  % Create a color map with a unique color for each face

            patch('Vertices', points0', 'Faces', app.f, 'FaceVertexCData', colorMap, 'FaceColor', 'flat');
            % patch("faces",app.f,'Vertices',points0','facecolor','g')
            plot3(0, 0, 0, '*', 'MarkerSize', 15);
            
            drawnow();
        end

        % Button pushed function: initializeButton
        function initializeButtonPushed(app, event)
            plot3(0,0,0);
            xlabel("x");
            ylabel("y");
            zlabel("z");
            grid on;
            axis([-8 8 -8 8 -8 8])
            axis square;
            hold on;
            
            clc;
        end

        % Value changed function: ChooseAxisDropDown
        function ChooseAxisDropDownValueChanged(app, event)
            app.thetaSlider.Value= 0;
            eventStruct.Value = app.thetaSlider.Value;
            app.thetaSliderValueChanging(eventStruct);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.thetaSliderLabel = uilabel(app.UIFigure);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [-360 360];
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.ChooseAxisDropDownLabel = uilabel(app.UIFigure);
            app.ChooseAxisDropDownLabel.Text = 'Choose Axis';
            app.ChooseAxisDropDown = uidropdown(app.UIFigure);
            app.ChooseAxisDropDown.Items = {'X', 'Y', 'Z'};
            app.ChooseAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseAxisDropDownValueChanged, true);
            app.ChooseAxisDropDown.Value = 'X';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.initializeButton = uibutton(app.UIFigure, 'push');
            app.initializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeButtonPushed, true);
            app.initializeButton.Text = 'initialize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end