classdef Lin_Li_Hw3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        theta4Slider       matlab.ui.control.Slider
        theta4SliderLabel  matlab.ui.control.Label
        theta3Slider       matlab.ui.control.Slider
        theta3SliderLabel  matlab.ui.control.Label
        d3Label            matlab.ui.control.Label
        d1Slider_2         matlab.ui.control.Slider
        theta1Slider       matlab.ui.control.Slider
        theta1SliderLabel  matlab.ui.control.Label
        initializeButton   matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        theta2Slider       matlab.ui.control.Slider
        theta2SliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        
        Fb;Vb;F1;F2;F3;F4;V1;V2;V3;V4;
        
        th1=0;
        th2=0;
        th3=0;
        th4=0;
        d1=0;
    end
    
    methods (Access = private)
        
        
        function [] = drawrobot(app)
            cla;
            pb=app.Vb;
            fcs=['g'];
            patch('Faces',app.Fb,'Vertices',pb(1:3,:)','facecolor',fcs);
            
            T1=[1 0 0 0;
                0 1 0 0;
                0 0 1 -1;
                0 0 0 1];
            T1(1:3,1:3) = rotz(app.th1);
            p1=T1*app.V1;
            p1=p1(1:3,:);
            fcs=['r'];
            patch('Faces',app.F1,'Vertices',p1','facecolor',fcs);

            T2=[1 0 0 15;
                0 1 0 0;
                0 0 1 2.5;
                0 0 0 1];
            p2=app.V2;
            Tr2 = eye(4);
            Tr2(1:3,1:3) = rotx(app.th2);
            T2 = T2*Tr2;
            p2=T1*T2*p2;
            p2 = p2(1:3,:);
            fcs=['b'];
            patch('Faces',app.F2,'Vertices',p2','facecolor',fcs);
            T3=[1 0 0 2.5;
                0 1 0 0;
                0 0 1 10;
                0 0 0 1];
            Tr3 = eye(4);
            Tr3(1:3,1:3) = rotz(app.th3);
            T3 = T3*Tr3;
            p3=T1*T2*T3*app.V3;
            p3=p3(1:3,:);
            fcs=['y'];
            patch('Faces',app.F3,'Vertices',p3','facecolor',fcs);
            % T11=[1 0 0 (app.d3+13)*cosd(app.th2);
            %     0 1 0 (app.d3+13)*sind(app.th2);
            %     0 0 1 app.d1+1;
            %     0 0 0 1];
            T4=[1 0 0 2+app.d1;
                0 1 0 0;
                0 0 1 2.25;
                0 0 0 1];
            Tr4 = eye(4);
            Tr4(1:3,1:3) = rotx(app.th4);
            T4 = T4*Tr4;
            p4=app.V4;
            p4 = T1*T2*T3*T4*p4;
            p4=p4(1:3,:);
            fcs=['y'];
            patch('Faces',app.F4,'Vertices',p4','facecolor',fcs);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: theta2Slider
        function theta2SliderValueChanging(app, event)
            app.th2 = event.Value;      
            app.drawrobot();
        end

        % Button pushed function: initializeButton
        function initializeButtonPushed(app, event)
            plot3(0,0,0);
            xlabel("x");
            ylabel("y");
            zlabel("z");
            grid on;
            axis([-30 30 -30 30 -30 30])
            axis square;
            hold on;
            
            cla;
            [app.Fb,app.Vb]=stlread_mod("CSB.stl");
            [app.F1,app.V1]=stlread_mod("link1.stl");
            [app.F2,app.V2]=stlread_mod("link2.stl");
            [app.F3,app.V3]=stlread_mod("link3.stl");
            [app.F4,app.V4]=stlread_mod("link4.stl");
            app.drawrobot();
        end

        % Value changing function: theta1Slider
        function theta1SliderValueChanging(app, event)
            app.th1 = event.Value;
            app.drawrobot();
        end

        % Value changing function: d1Slider_2
        function d1Slider_2ValueChanging(app, event)
            app.d1 = event.Value;
            app.drawrobot();
        end

        % Value changing function: theta3Slider
        function theta3SliderValueChanging(app, event)
            app.th3 = event.Value;
            app.drawrobot();
            
        end

        % Value changing function: theta4Slider
        function theta4SliderValueChanging(app, event)
            app.th4 = event.Value;
            app.drawrobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.theta2SliderLabel = uilabel(app.UIFigure);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.UIFigure);
            app.theta2Slider.Limits = [-360 360];
            app.theta2Slider.ValueChangingFcn = createCallbackFcn(app, @theta2SliderValueChanging, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.initializeButton = uibutton(app.UIFigure, 'push');
            app.initializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeButtonPushed, true);
            app.initializeButton.Text = 'initialize';
            app.theta1SliderLabel = uilabel(app.UIFigure);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.UIFigure);
            app.theta1Slider.Limits = [-360 360];
            app.theta1Slider.ValueChangingFcn = createCallbackFcn(app, @theta1SliderValueChanging, true);
            app.d1Slider_2 = uislider(app.UIFigure);
            app.d1Slider_2.Limits = [0 5];
            app.d1Slider_2.ValueChangingFcn = createCallbackFcn(app, @d1Slider_2ValueChanging, true);
            app.d3Label = uilabel(app.UIFigure);
            app.d3Label.Text = 'd1';
            app.theta3SliderLabel = uilabel(app.UIFigure);
            app.theta3SliderLabel.Text = 'theta3';
            app.theta3Slider = uislider(app.UIFigure);
            app.theta3Slider.Limits = [-360 360];
            app.theta3Slider.ValueChangingFcn = createCallbackFcn(app, @theta3SliderValueChanging, true);
            app.theta4SliderLabel = uilabel(app.UIFigure);
            app.theta4SliderLabel.Text = 'theta4';
            app.theta4Slider = uislider(app.UIFigure);
            app.theta4Slider.Limits = [-360 360];
            app.theta4Slider.ValueChangingFcn = createCallbackFcn(app, @theta4SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lin_Li_Hw3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end